classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TrafficControlPolicySwitch  matlab.ui.control.Switch
        TrafficControlPolicySwitchLabel  matlab.ui.control.Label
        FireButton                  matlab.ui.control.StateButton
        TypeEditField               matlab.ui.control.NumericEditField
        TypeEditFieldLabel          matlab.ui.control.Label
        ApplyButton                 matlab.ui.control.StateButton
        GoalBlueDropDown            matlab.ui.control.DropDown
        GoalBlueDropDownLabel       matlab.ui.control.Label
        StartBlueDropDown           matlab.ui.control.DropDown
        StartBlueDropDownLabel      matlab.ui.control.Label
        GoalRedDropDown             matlab.ui.control.DropDown
        GoalRedDropDownLabel        matlab.ui.control.Label
        StartRedDropDown            matlab.ui.control.DropDown
        StartRedDropDownLabel       matlab.ui.control.Label
        ConnectivityEdgesLabel      matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        Image                       matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
        ReachGraph                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        G = digraph()
        path_Red
        path_Blue
        g1
        g2
        g3
        PNet
        type
        Elefirst
        P2T
        T2P
        x0
        Order
        choice
        Special
    
    end
    
    methods (Access = public)
        
        function Calculated(app)
            un_in = unique([unique([app.path_Blue app.path_Red]) [1:5]]);
            n = hist([app.path_Blue app.path_Red [1:5]],numel(un_in));
            oputput = un_in(n==1);
            if(numel(app.path_Red)==2)
                app.Special = 1
                app.path_Red = [oputput(1) app.path_Red]
            elseif(numel(app.path_Blue)==2)
                app.Special = 2
                app.path_Blue = [oputput(1) app.path_Blue]
            else
                app.Special = 0
            end
            switch numel(intersect(app.path_Red,app.path_Blue))
                case 3
                    app.type = 1
                case 1
                    app.type = 2
                case 2
                    check = app.path_Blue;
                    check([1 3])=check([3 1]);
                    if(numel(find(check==app.path_Red))==2)
                        app.type = 3
                        if(app.Special == 1)
                            app.Elefirst = 1;%Blue
                        elseif(app.Special == 2)
                            app.Elefirst = 0; %RED
                        elseif(app.path_Blue([3]) == app.path_Red([1]))
                            app.Elefirst = 0; %RED
                        elseif(app.path_Blue([1]) == app.path_Red([3]))
                            app.Elefirst = 1;%Blue
                        end
                    else
                        app.type = 2
                    end
            end
            app.TypeEditField.Value = app.type;
        end
        
        function [p_en,t_en] = update(app,PetriNet,u)
          
            p_en = PetriNet.fire(u);
            t_en = PetriNet.checkTransEn;
            
        end
        function [p,A,B] = PrintTree(app,PetriNet,p,I,O,A,B)
            PetriNet_t = PetriNet;
            t_en = PetriNet_t.checkTransEn;
            A0 = zeros(1,numel(t_en));
            p_en1 = PetriNet_t.fire(zeros(1,PetriNet.numTrans));
            find(t_en);
            for i = find(t_en)
                i;
                A0(i) = 1;
                p_en = PetriNet_t.fire(A0);
                if any(cellfun(@isequal, p, repmat({p_en}, size(p))))
        %             disp('Reeeeeeeeeeeeeee');
                else
                    p(end+1) = {p_en};
                    [p,A,B] = PrintTree(app,PetriNet_t,p,I,O,A,B);
                end
                PetriNet_t = petriNet(I,O,p_en1);
                A0 = zeros(1,numel(t_en));
                A(end+1) = find(cellfun(@(p) isequal(p, p_en1), p));
                B(end+1) = find(cellfun(@(p) isequal(p, p_en), p));
            
            end
        end
        
        function ReachFind(app)
            P2T = app.P2T{app.type};
            T2P = app.T2P{app.type};
            n = numel(P2T);
            m = numel(T2P);
            I = zeros(n,m);
            O = zeros(m,n);
            for i = 1:n
                I(i,P2T{i}) = 1;
            end
            for j = 1:m
                O(j,T2P{j}) = 1;
            end
             
            PetriNet = petriNet(I,O,app.x0{app.type});
            app.PNet = petriNet(I,O,app.x0{app.type});
            u = zeros(1,PetriNet.numTrans);
            [p_en,t_en] =update(app,PetriNet,u);
            t_en = PetriNet.checkTransEn;
            p ={PetriNet.fire(u)};
            G = digraph();
            A =[];
            B =[];
            [p,A,B] = PrintTree(app,PetriNet,p,I,O,A,B);
            G = addedge(G,A,B);
            plot(app.ReachGraph,G);
            t_en = find(app.PNet.checkTransEn==1);
            a = zeros(1,numel(app.PNet.checkTransEn));
            if(app.Special ==1)
                if(app.type==3)
                    a(t_en(2)) = 1;
                else
                    a(t_en(1)) = 1;
                end
            elseif(app.Special==2)
                a(t_en(2)) = 1;
            end
            app.PNet.fire(a)
        end
        
        function FireOutput(app,Order_Red,Order_Blue)
            %Red
            t_en = find(app.PNet.checkTransEn==1)
            a = zeros(1,numel(app.PNet.checkTransEn));
            
            if (app.PNet.checkTransEn == zeros(1,numel(app.PNet.checkTransEn)))
            else
                a(t_en(1)) = 1
                Current = app.PNet.fire(a);
            
                a = app.path_Red*Current(Order_Red)';
                
                %Blue
                b = app.path_Blue*Current(Order_Blue)';
                
                un_in = unique([unique([app.path_Blue app.path_Red]) [1:5]]);
                n = hist([app.path_Blue app.path_Red [1:5]],numel(un_in));
                oputput = un_in(n==1);
                if(a==0)
                    a = oputput(1);
                elseif(b==0)
                    b = oputput(1);
                end
                
                hold(app.UIAxes,"on")
                highlight(app.g1,a,'NodeColor','r','MarkerSize',15)
                highlight(app.g1,b,'NodeColor','cyan','MarkerSize',10)
                hold(app.UIAxes,'off')
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;load('PetrinetType.mat');
            app.P2T = P2T;
            app.T2P = T2P;
            app.x0 = x0;
            s = [1,2,3,3,3,3,4,5]';
            t = [3,3,1,2,4,5,3,3]';
            vars = {'s','t'};
%             T = array2table([a;b]','VariableNames',vars)
            ConEdge = table(s,t,'VariableNames',vars);
            app.UITable.Data = ConEdge;
            s = app.UITable.Data.s;
            t = app.UITable.Data.t;
            app.G = addedge(app.G,s,t);
            app.g1 = plot(app.UIAxes,app.G,'o-');
            app.g1.XData;

            
            %Start&Goal
            maximum = max([s;t]);
            app.choice = cell(maximum(end),1);
            for i = 1:(maximum(end));app.choice(i)={int2str(i)};end
            app.StartRedDropDown.Items = app.choice;
            app.GoalRedDropDown.Items = app.choice;
            app.StartBlueDropDown.Items = app.choice;
            app.GoalBlueDropDown.Items = app.choice;
            
            %Order
            app.Order = {};
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            delete(app.g2)
            delete(app.g3)
            delete(app.g1)
            app.g1 = plot(app.UIAxes,app.G,'o-');
            
            S_Red = str2double(app.StartRedDropDown.Value);
            G_Red = str2double(app.GoalRedDropDown.Value);
            S_Blue = str2double(app.StartBlueDropDown.Value);
            G_Blue = str2double(app.GoalBlueDropDown.Value);
            
            %Highlight shortest path
            app.path_Red = shortestpath(app.G,S_Red,G_Red);
            app.path_Blue = shortestpath(app.G,S_Blue,G_Blue);
            
            %Highlight first node
            highlight(app.g1,app.path_Red(1),'NodeColor','r','MarkerSize',10)
            highlight(app.g1,app.path_Blue(1),'NodeColor','cyan','MarkerSize',10)
            
            Calculated(app)
            ReachFind(app)
        end

        % Value changed function: FireButton
        function FireButtonValueChanged(app, event)
            delete(app.g1)
            app.g1 = plot(app.UIAxes,app.G,'o-');
            
            switch app.type
                case 1
                    FireOutput(app,[1:3],[1:3]+5)
                case 2
                    FireOutput(app,[1:3],[1:3]+3)
                case 3
                    if(app.Elefirst == 0)
                        FireOutput(app,[1:3],[1:3]+3)
                    else
                        FireOutput(app,[1:3]+3,[1:3])
                    end
                    
            end
            
        end

        % Value changed function: StartRedDropDown
        function StartRedDropDownValueChanged(app, event)
            value = app.StartRedDropDown.Value;
            Reddrop = app.choice;
            Reddrop(ismember(Reddrop,value)) = [];
            app.GoalRedDropDown.Items = Reddrop;
        end

        % Value changed function: GoalRedDropDown
        function GoalRedDropDownValueChanged(app, event)
            value = app.GoalRedDropDown.Value;
            Reddrop = app.choice;
            Reddrop(ismember(Reddrop,value)) = [];
%             app.StartRedDropDown.Items = Reddrop;
        end

        % Value changed function: StartBlueDropDown
        function StartBlueDropDownValueChanged(app, event)
            value = app.StartBlueDropDown.Value;
            Bluedrop = app.choice;
            Bluedrop(ismember(Bluedrop,value)) = [];
            app.GoalBlueDropDown.Items = Bluedrop;
        end

        % Value changed function: GoalBlueDropDown
        function GoalBlueDropDownValueChanged(app, event)
            value = app.GoalBlueDropDown.Value;
            Bluedrop = app.choice;
            Bluedrop(ismember(Bluedrop,value)) = [];
%             app.StartBlueDropDown.Items = Bluedrop;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReachGraph = uiaxes(app.UIFigure);
            title(app.ReachGraph, 'Reachability Tree')
            app.ReachGraph.XTick = [];
            app.ReachGraph.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'intersection.PNG';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'s'; 't'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ConnectivityEdgesLabel = uilabel(app.UIFigure);
            app.ConnectivityEdgesLabel.Text = 'Connectivity Edges';
            app.StartRedDropDownLabel = uilabel(app.UIFigure);
            app.StartRedDropDownLabel.Text = 'Start.Red';
            app.StartRedDropDown = uidropdown(app.UIFigure);
            app.StartRedDropDown.Items = {'1', '2', '3', '4'};
            app.StartRedDropDown.ValueChangedFcn = createCallbackFcn(app, @StartRedDropDownValueChanged, true);
            app.StartRedDropDown.Value = '2';
            app.GoalRedDropDownLabel = uilabel(app.UIFigure);
            app.GoalRedDropDownLabel.Text = 'Goal.Red';
            app.GoalRedDropDown = uidropdown(app.UIFigure);
            app.GoalRedDropDown.Items = {'1', '2'};
            app.GoalRedDropDown.ValueChangedFcn = createCallbackFcn(app, @GoalRedDropDownValueChanged, true);
            app.GoalRedDropDown.Value = '1';
            app.StartBlueDropDownLabel = uilabel(app.UIFigure);
            app.StartBlueDropDownLabel.Text = 'Start.Blue';
            app.StartBlueDropDown = uidropdown(app.UIFigure);
            app.StartBlueDropDown.Items = {'1', '2', '3', '4'};
            app.StartBlueDropDown.ValueChangedFcn = createCallbackFcn(app, @StartBlueDropDownValueChanged, true);
            app.StartBlueDropDown.Value = '4';
            app.GoalBlueDropDownLabel = uilabel(app.UIFigure);
            app.GoalBlueDropDownLabel.Text = 'Goal.Blue';
            app.GoalBlueDropDown = uidropdown(app.UIFigure);
            app.GoalBlueDropDown.Items = {'1', '2'};
            app.GoalBlueDropDown.ValueChangedFcn = createCallbackFcn(app, @GoalBlueDropDownValueChanged, true);
            app.GoalBlueDropDown.Value = '1';
            app.ApplyButton = uibutton(app.UIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            app.TypeEditFieldLabel = uilabel(app.UIFigure);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TypeEditField.Editable = 'off';
            app.FireButton = uibutton(app.UIFigure, 'state');
            app.FireButton.ValueChangedFcn = createCallbackFcn(app, @FireButtonValueChanged, true);
            app.FireButton.Text = 'Fire';
            app.TrafficControlPolicySwitchLabel = uilabel(app.UIFigure);
            app.TrafficControlPolicySwitchLabel.Text = 'Traffic Control Policy';
            app.TrafficControlPolicySwitch = uiswitch(app.UIFigure, 'slider');
            app.TrafficControlPolicySwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end